classdef V3_GGCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        panel_homescreen                matlab.ui.container.Panel
        ChangeInputsButton              matlab.ui.control.Button
        GrowGreenImpactCalculatorLabel  matlab.ui.control.Label
        InformationaboutNatureBasedSolutionsButton  matlab.ui.control.Button
        UrbanForestButton               matlab.ui.control.Button
        GreenBlueCorridorButton         matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        GreenRooPageButton              matlab.ui.control.Button
        Image_backgrnd                  matlab.ui.control.Image
        panel_nbsinfo                   matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        GreenBlueCorridorLabel          matlab.ui.control.Label
        UrbanForestLabel                matlab.ui.control.Label
        GreenRoofLabel                  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        button_back_NBSinfo             matlab.ui.control.Button
        panel_bluegreencorridor_calculator  matlab.ui.container.Panel
        SpinnerAreaOverallGBC           matlab.ui.control.Spinner
        SpinnerLabelAreaOverallGBC      matlab.ui.control.Label
        SpinnerAverageRainGBC           matlab.ui.control.Spinner
        SpinnerLabelAverageRainGBC      matlab.ui.control.Label
        SpinnerAreaPermPaveGBC          matlab.ui.control.Spinner
        SpinnerLabelAreaPermPaveGBC     matlab.ui.control.Label
        SpinnerGrassAreaGBC             matlab.ui.control.Spinner
        SpinnerLabelGrassAreaGBC        matlab.ui.control.Label
        SpinnerTimeFrameGBC             matlab.ui.control.Spinner
        SpinnerLabelTimeFrameGBC        matlab.ui.control.Label
        SpinnerRetAreaGBC               matlab.ui.control.Spinner
        SpinnerLabelTetAreaGBC          matlab.ui.control.Label
        SpinnerNmbrTreesGBC             matlab.ui.control.Spinner
        SpinnerLabelNmbrTreesGBC        matlab.ui.control.Label
        Lamp_GBC_green                  matlab.ui.control.Lamp
        Lamp_GBC_yellow                 matlab.ui.control.Lamp
        Lamp_GBC_red                    matlab.ui.control.Lamp
        panel_extresults                matlab.ui.container.Panel
        Label_activeResults             matlab.ui.control.Label
        button_back_extresults          matlab.ui.control.Button
        plot_Impacts                    matlab.ui.control.UIAxes
        plot_impactbars                 matlab.ui.control.UIAxes
        plot_cashflowovertime           matlab.ui.control.UIAxes
        plot_montecarlo                 matlab.ui.control.UIAxes
        Button_3                        matlab.ui.control.Button
        CostBenefitRatioEditField_GBC   matlab.ui.control.NumericEditField
        CostBenefitRatioEditFieldLabel_GBC  matlab.ui.control.Label
        RateofReturnEditField_GBC       matlab.ui.control.NumericEditField
        RateofReturnEditFieldLabel_GBC  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        plot_costbenefit_GBC            matlab.ui.control.UIAxes
        InputsLabel_3                   matlab.ui.control.Label
        button_GBC_extresults           matlab.ui.control.Button
        NetPresentValueEditField_GBC    matlab.ui.control.NumericEditField
        NPVLabel_GBC                    matlab.ui.control.Label
        LabelErrorInput_GBC             matlab.ui.control.Label
        ResultsLabel_3                  matlab.ui.control.Label
        CalculateButton_GBC             matlab.ui.control.Button
        button_back2homescreen_bluegreencorridor  matlab.ui.control.Button
        Image4_3                        matlab.ui.control.Image
        panel_greenroof_calculator      matlab.ui.container.Panel
        lifetimeanalysis4055yearsLabel  matlab.ui.control.Label
        KeepTimeFrameatzeroforprobabilisticLabel  matlab.ui.control.Label
        Lamp_gr_green                   matlab.ui.control.Lamp
        Lamp_gr_yellow                  matlab.ui.control.Lamp
        Lamp_gr_red                     matlab.ui.control.Lamp
        Button                          matlab.ui.control.Button
        CostBenefitRatioEditField       matlab.ui.control.NumericEditField
        CostBenefitRatioEditFieldLabel  matlab.ui.control.Label
        RateofReturnEditField           matlab.ui.control.NumericEditField
        RateofReturnEditFieldLabel      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        plot_costbenefit                matlab.ui.control.UIAxes
        InputsLabel                     matlab.ui.control.Label
        button_back_greenroof           matlab.ui.control.Button
        DisplayedarethemostprobableResultsLabel  matlab.ui.control.Label
        button_greenroof_extresults     matlab.ui.control.Button
        NetPresentValueEditField        matlab.ui.control.NumericEditField
        NPVLabel                        matlab.ui.control.Label
        LabelErrorInput                 matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        CalculateButton_GR              matlab.ui.control.Button
        TimeFrameyearsSpinner           matlab.ui.control.Spinner
        TimeFrameyearsSpinnerLabel      matlab.ui.control.Label
        AverageRaininAreaperMonthmmMonthSpinner  matlab.ui.control.Spinner
        AverageRaininAreaperMonthmmMonthLabel  matlab.ui.control.Label
        GreenRoofSizem2Spinner          matlab.ui.control.Spinner
        GreenRoofSizem2SpinnerLabel     matlab.ui.control.Label
        TypeofGreenRoofButtonGroup      matlab.ui.container.ButtonGroup
        IntensiveButton                 matlab.ui.control.RadioButton
        ExtensiveButton                 matlab.ui.control.RadioButton
        Image4_4                        matlab.ui.control.Image
        panel_urbanforest_calculator    matlab.ui.container.Panel
        SpinnerAreaOverallUrb_2         matlab.ui.control.Spinner
        SpinnerLabelAreaOverallUrb_2    matlab.ui.control.Label
        SpinnerAverageRainUrb           matlab.ui.control.Spinner
        SpinnerLabelAverageRainUrb      matlab.ui.control.Label
        SpinnerGrassAreaUrb             matlab.ui.control.Spinner
        SpinnerLabelGrassAreaUrb        matlab.ui.control.Label
        SpinnerTimeFrameUrb             matlab.ui.control.Spinner
        SpinnerLabelTimeFrameUrb        matlab.ui.control.Label
        SpinnerRetAreaUrb               matlab.ui.control.Spinner
        SpinnerLabelTetAreaUrb          matlab.ui.control.Label
        SpinnerNmbrTreesUrb             matlab.ui.control.Spinner
        SpinnerLabelNmbrTreesUrb        matlab.ui.control.Label
        Lamp_Urb_green                  matlab.ui.control.Lamp
        Lamp_Urb_yellow                 matlab.ui.control.Lamp
        Lamp_Urb_red                    matlab.ui.control.Lamp
        Button_2                        matlab.ui.control.Button
        CostBenefitRatioEditField_Urb   matlab.ui.control.NumericEditField
        CostBenefitRatioEditFieldLabel_Urb  matlab.ui.control.Label
        RateofReturnEditField_Urb       matlab.ui.control.NumericEditField
        RateofReturnEditFieldLabel_Urb  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        plot_costbenefit_Urb            matlab.ui.control.UIAxes
        InputsLabel_2                   matlab.ui.control.Label
        button_urb_extresults_2         matlab.ui.control.Button
        NetPresentValueEditField_Urb    matlab.ui.control.NumericEditField
        NPVLabel_Urb                    matlab.ui.control.Label
        LabelErrorInput_Urb             matlab.ui.control.Label
        ResultsLabel_2                  matlab.ui.control.Label
        CalculateButton_Urb             matlab.ui.control.Button
        button_back2homescreen_urbanforest  matlab.ui.control.Button
        Image4_2                        matlab.ui.control.Image
        panel_calculator_inputs         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        Tab2                            matlab.ui.container.Tab
        button_back2homescreen_inputs   matlab.ui.control.Button
    end


    properties (Access = public)


        %general imput variables
        n = 50000; %Number of Simulation iterations

        y_upper = 55; %upper year limit
        y_lower = 40; %lower year limit
        timespan_chosen = 0;

        discountrate_upper = 0.08; %upper discount rate
        discountrate_lower = 0.02; %lower discount rate

        inflation_upper = 0.07; %upper inflation rate
        inflation_lower = 0; %lower inflation rate

        calculate_var;


        %Impact Matrix ---------------------------------------------------
        %Row structures: (lower limit, upperlimit, distribution, time-frame)
        %->distribution (dist: 1 = normal, 2 = uniform, 3 = triangular, 4 = const)
        %   in case of constant: lowerlimit = upper, insert in lower limit !!!!!)
        % ->time-frame 1 = one time, 2 = yearly, 3 = every 20 years, 0 =
        % initial construction cost


        %Personal impacts of extensive green roof - testing matrix <------------
        solv_matr_test = [
            -165,-130, 2,1;%one time
            132.6, 174, 2,1;
            0, 48,2,1;
            39,100,2,1;
            0,0.38,2,2; % anually
            0.18,0.68,2,2;
            0.22,0.22,4,2;
            -13.46,-0.65,2,2;
            161.46,161.46,4,3; % every 20 years Simplification !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            ];

        solv_matr_labels_test = {'Initial Cost';
            'Property Value';
            'Tax Reduction';
            'Water retention';
            'Cooling';
            'Heating';
            'Avoided Infrastructure';
            'Operation & Maintanance';
            'Longevity';
            };


        %solution Variables

        solv_matr;
        solv_matr_labels;

        mres = [0];
        solv_matr_average = [0];
        most_prob_NPV = [0];
        most_prob_RR = [0];
        most_prob_CBR = [0];
        acc_improvement = 0;


        %panel management
        current_panel;


    end

    methods (Access = private)

        function gg_calculate(app)


            %waitbar

            if app.calculate_var == 1
            %calculating animation 
            %start proccessing animation
            % Change button name to "Processing"
            app.CalculateButton_GR.Text = '';
            % Put text on top of icon
            app.CalculateButton_GR.IconAlignment = 'center';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalculateButton_GR.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalculateButton_GR.Icon = wbar;
            % Loop through something and update waitbar

            elseif app.calculate_var == 3

                    %calculating animation 
            %start proccessing animation
            % Change button name to "Processing"
            app.CalculateButton_Urb.Text = '';
            % Put text on top of icon
            app.CalculateButton_Urb.IconAlignment = 'center';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalculateButton_Urb.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalculateButton_Urb.Icon = wbar;
            % Loop through something and update waitbar


            elseif app.calculate_var == 2

                    %calculating animation 
            %start proccessing animation
            % Change button name to "Processing"
            app.CalculateButton_GBC.Text = '';
            % Put text on top of icon
            app.CalculateButton_GBC.IconAlignment = 'center';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalculateButton_GBC.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalculateButton_GBC.Icon = wbar;
            % Loop through something and update waitbar

            end


            count = 0;


            fprintf('calc start');

            %solution Variables

            mres = [0];

            solv_matr_average = [0];

            most_prob_NPV = [0];
            most_prob_RR = [0];
            most_prob_CBR = [0];


            for i = [1:app.n]


            if app.calculate_var == 1
                %Update waitbar
                % Update image data (royalblue)
                if mod(i,500)==0 % update every 10 cycles; improves efficiency
                    currentProg = min(round((size(wbar,2)-2)*(i/app.n)),size(wbar,2)-2);
                    RGB = app.CalculateButton_GR.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.CalculateButton_GR.Icon = RGB;
                    % Pause to slow down animation
                    pause(.003)
                end


            elseif app.calculate_var == 2
                    %Update waitbar
                % Update image data (royalblue)
                if mod(i,500)==0 % update every 10 cycles; improves efficiency
                    currentProg = min(round((size(wbar,2)-2)*(i/app.n)),size(wbar,2)-2);
                    RGB = app.CalculateButton_GBC.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.CalculateButton_Urb.Icon = RGB;
                    % Pause to slow down animation
                    pause(.003)
                end


            elseif app.calculate_var == 3
                    %Update waitbar
                % Update image data (royalblue)
                if mod(i,500)==0 % update every 10 cycles; improves efficiency
                    currentProg = min(round((size(wbar,2)-2)*(i/app.n)),size(wbar,2)-2);
                    RGB = app.CalculateButton_Urb.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.CalculateButton_GR.Icon = RGB;
                    % Pause to slow down animation
                    pause(.003)
                end


            end


                solv_matr = app.solv_matr; %choose impact matrix

                %choose timespan
                if app.timespan_chosen == 0
                    y = round((app.y_upper-app.y_lower).*rand(1)+app.y_lower); %Random year from chosen timespan
                else
                    y = app.timespan_chosen;
                end


                %Random combination of Discountrate and Inflation ----------------------
                discountrate = ((app.discountrate_upper-app.discountrate_lower).*rand(1)+app.discountrate_lower) - ((app.inflation_upper-app.inflation_lower).*rand(1)+app.inflation_lower); %Random discountrate from chosen delta

                %Check !!!!!!!!!!!!!


                %check if discountrate is in bounds
                if  discountrate < app.discountrate_lower-app.inflation_upper || app.discountrate_upper-app.inflation_lower < discountrate
                    fprintf('discountrate Boundry Overstep 1 !');
                    return
                end


                %discounted cashflow analysis for anual cashflows ---------------------
                for m = 1:size(app.solv_matr,1)

                    impact_lower = solv_matr(m,1);
                    impact_upper = solv_matr(m,2);

                    if solv_matr(m,4) == 1 % if a impact is only one time, at this stage nothing has to be done

                    end


                    if solv_matr(m,4) == 2 % If Impact is yearly a discounted cash flow analysis has to be performed


                        sum_impact_lower_previous = 0;
                        sum_impact_upper_previous = 0;

                        sum_impact_lower = 0;
                        sum_impact_upper = 0;


                        for t=0:y

                            discounted_impact_lower = impact_lower/((1+discountrate)^t); %discount for every year t
                            discounted_impact_upper = impact_upper/((1+discountrate)^t); %discount for every year t

                            sum_impact_lower = sum_impact_lower_previous + discounted_impact_lower;
                            sum_impact_upper = sum_impact_upper_previous + discounted_impact_upper;

                            sum_impact_lower_previous = sum_impact_lower;
                            sum_impact_upper_previous = sum_impact_upper;

                        end
                        solv_matr(m,1) = sum_impact_lower;
                        solv_matr(m,2) = sum_impact_upper;
                    end


                    if solv_matr(m,4) == 3 %If a impact is every 20 years, the impact has to be multiplied for every 20 years reached, discounted !!!!!!!!!!!!!!!!


                        solv_matr(m,1) = 0;
                        solv_matr(m,2) = 0;


                        for n = 0:(floor(y/20)-1)
                            t_impact_3 = n*20; %current year (every 20 years)

                            impact_lower_20y  = impact_lower/((1+discountrate)^t_impact_3);
                            impact_upper_20y  = impact_upper/((1+discountrate)^t_impact_3);

                            solv_matr(m,1)= solv_matr(m,1)+impact_lower_20y;
                            solv_matr(m,2)= solv_matr(m,2)+impact_upper_20y;

                        end

                    end
                end


                %Data collection for Validation
                %Average upper & lower limits

                solv_matr_collect_lower(1:size(solv_matr,1),i)= solv_matr(:,1); %collection of all simulated lower values
                solv_matr_collect_upper(1:size(solv_matr,1),i)= solv_matr(:,2); %collection of all simulated upper values

                solv_matr_collect_lower(size(solv_matr,1)+1,i)= discountrate;
                solv_matr_collect_upper(size(solv_matr,1)+1,i)= discountrate;


                %Monte Carlo Simulation --------------------------------------------

                count = count +1;
                npv_iterative = 0;
                sum_old = 0;
                ICC_old = 0;
                ICC_sum = 0;

                for g = 1:size(solv_matr,1)

                    %set zero for next iteration
                    r_norm = 0;
                    r_uniform = 0;
                    r_triang = 0;
                    r_const = 0;
                    ICC = 0;


                    a = solv_matr(g,1); %lower limit of Impact
                    b = solv_matr(g,2); %upper limit of Impact


                    %Decision betweenDistributions


                    if solv_matr(g,3) == 1
                        r_norm = normrnd((b+a)/2,2,[1]); %Generation of random normal dist Values

                        if solv_matr(g,4) == 0 % check for ICC
                            ICC = r_norm;
                        end

                    elseif solv_matr(g,3) == 2
                        r_uniform = (b-a).*rand(1)+a; %Generation of random uniform dist Values

                        if solv_matr(g,4) == 0 % check for ICC
                            ICC = r_uniform;
                        end

                        %{
                        %Checking for boundry overstep
                        if abs(r_uniform) < abs(a) || abs(b) < abs(r_uniform)
                            fprintf('Boundry overstep uniform')
                            return
                        end
                        %}

                    elseif solv_matr(g,3) == 3 %triangular distribution
                        pd_triang = makedist("Triangular",'a',abs(a),'b',(abs(a)+abs(b))/2,'c',abs(b));%with abs value for model
                        r_triang_pre = random(pd_triang,1); %Generation of random Triangular dist Values
                        r_triang = (a/abs(a))*r_triang_pre; %reinsert positive/negative

                        if a == 0
                            r_triang = r_triang_pre; % in case of a = 0, as 0/0=NaN
                        end

                        if solv_matr(g,4) == 0 % check for ICC
                            ICC = r_triang;
                        end

                    elseif solv_matr(g,3) == 4
                        r_const = solv_matr(g,1); %Taking over value for constant impacts

                        if solv_matr(g,4) == 0 % check for ICC
                            ICC = r_const;
                        end

                        if solv_matr(g,1) ~= solv_matr(g,2) %validation of value being constant
                            print('const Value not constant !!!! Please check')
                            return
                        end

                    else
                        print('Incomplete distr input, please check distr selection in row') %Throw error if distribution not chosen correctly
                        break
                    end

                    %summation of simulated solv matr
                    npv_iterative = sum_old+r_norm+r_uniform+r_triang+r_const; %Summation of random variables for ech impact
                    mres(i,g+2)=r_norm+r_uniform+r_triang+r_const;
                    sum_old = npv_iterative;

                    %summation ICC
                    ICC_sum = ICC_sum + ICC;
                    ICC_old = ICC;
                    ICC_matr(i) = ICC_sum;

                end

                %Initial construction cost Matrix
                ICC_matr(i) = ICC_sum;

                mres(i,2) = npv_iterative; %collecting Data for each iteration

            end


            app.mres = mres;


            %Means and limits for validation
            mres_mean= mean(mres(:,2));
            mres_max = max(mres(:,2));

            sum_min_solv = sum(solv_matr(:,1));
            sum_max_solv = sum(solv_matr(:,2));

            discountrate_min = min(solv_matr_collect_lower(size(solv_matr,1)+1,:));
            discountrate_max = max(solv_matr_collect_lower(size(solv_matr,1)+1,:));

            %average creation for validation
            for j = 1:size(solv_matr_collect_lower,1)

                solv_matr_average(j,1)=mean(solv_matr_collect_lower(j,:));
                solv_matr_average(j,2)=mean(solv_matr_collect_upper(j,:));

                solv_matr_average(j,3)=max(solv_matr_collect_lower(j,:));
                solv_matr_average(j,4)=max(solv_matr_collect_upper(j,:));

                solv_matr_average(j,5)=min(solv_matr_collect_lower(j,:));
                solv_matr_average(j,6)=min(solv_matr_collect_upper(j,:));

            end

            app.solv_matr_average = solv_matr_average;


            %kernel density estimator
            [f,xi] = ksdensity(transpose(mres(:,2)));
            [max_pdf, pos_max_pdf] = max(f);
            app.most_prob_NPV = xi(pos_max_pdf);

            %lower limit npv 
            npv_min = min(mres(:,2))
            npv_max = max(mres(:,2))
            npv_average = (npv_min+npv_max)/2
            

            app.acc_improvement = abs(((app.most_prob_NPV/npv_average)*100)-100);

            %Economic Ratios
            ICC_matr
            [f_ICC,xi_ICC] = ksdensity(ICC_matr);
            [max_pdf_ICC, pos_max_pdf_ICC] = max(f_ICC);
            most_prob_ICC = xi_ICC(pos_max_pdf_ICC)

            app.most_prob_CBR = (app.most_prob_NPV+abs(most_prob_ICC))/abs(most_prob_ICC) %Generation of Rate of Return for all Iterations
            app.most_prob_RR = (app.most_prob_NPV)/abs(most_prob_ICC) %Generation of Cost Benefit Ratio for all Investments


            if app.calculate_var == 1
               % remove waitbar
            app.CalculateButton_GR.Icon = '';
            % Change button name
            app.CalculateButton_GR.Text = 'Calculate';
            elseif app.calculate_var == 2
                   
            % remove waitbar
            app.CalculateButton_GBC.Icon = '';
            % Change button name
            app.CalculateButton_GBC.Text = 'Calculate';


            elseif app.calculate_var == 3
            % remove waitbar
            app.CalculateButton_Urb.Icon = '';
            % Change button name
            app.CalculateButton_Urb.Text = 'Calculate';


            end


            fprintf('calc finish)')


            %figures -------------------------------------------


            %Fig 1: Distribution functions

            hold(app.plot_Impacts,'on')

            for k=1:size(solv_matr,1)
                plot(app.plot_Impacts,solv_matr_average(k,1:2),[k k],'LineStyle','-','Marker','|','LineWidth',1,'Color',['#82B366'],'MarkerEdgeColor',['#808080'])
            end

            yticks(app.plot_Impacts,1:size(solv_matr,1)+5);
            yticklabels(app.plot_Impacts,app.solv_matr_labels);

            xlabel(app.plot_Impacts,'Impact Distribution [€]')
            hold(app.plot_Impacts,'off')


            %Fig 2 : Monte Carlo simulation histogram
            hold(app.plot_montecarlo,'on')

            yyaxis(app.plot_montecarlo,'left')
            cla(app.plot_montecarlo)%Additional clear fig becaus other didnt work
            histogram(app.plot_montecarlo,mres(:,2),'FaceColor',['#82B366']);

            %general
            ylabel(app.plot_montecarlo,'Simulation Frequency [Sum]')
            xlabel(app.plot_montecarlo,'Net Present Value [€]')


            %kernel density estimator

            yyaxis(app.plot_montecarlo,'right')
            [f,xi] = ksdensity(app.plot_montecarlo,transpose(mres(:,2)));
            plot(app.plot_montecarlo,xi,f)
            ylabel(app.plot_montecarlo,'Prob density')


            %calculate most probable value
            [max_pdf, pos_max_pdf] = max(f);
            most_prob_PV = xi(pos_max_pdf);
            plot(app.plot_montecarlo,most_prob_PV,max_pdf,'Marker','.','MarkerSize',10,'Color','Black')
            text(app.plot_montecarlo,most_prob_PV+0.025*most_prob_PV,max_pdf+0.025*max_pdf,['most probable NPV ' num2str(xi(pos_max_pdf)) '€'])%displ text next to most prob value


            legend(app.plot_montecarlo,{'Frequency Summation Bins','Probability Density Function'},"Location","northwest")
            hold(app.plot_montecarlo,'off')


            %Fig 3: Diagramm Cashflow over time

            %discounted cashflow for average discountrate

            %Average years !!!!!!!!!!!

            discountrate_average = solv_matr_average(end,1);

            solv_matr = app.solv_matr;

            for m = 1:size(solv_matr,1)

                if solv_matr(m,4) == 1 %One time investments are performed for the beginning of the year
                    solve_matr_time_develop(m,1) = mean(solv_matr(m,1:2));
                end


                if solv_matr(m,4) == 2 % If Impact is yearly a discounted cash flow analysis has to be performed

                    impact_mean = mean(solv_matr(m,1:2));

                    for t=1:y
                        discounted_impact_mean = impact_mean/((1+discountrate_average)^t); %discount for every year t
                        solve_matr_time_develop(m,t)= discounted_impact_mean; %enter into matrixfor every year
                    end
                end


                if solv_matr(m,4) == 3 %If a impact is every 20 years, the impact has to be multiplied for every 20 years reached, discounted !!!!!!!!!!!!!!!!

                    for n = 0:(floor(y/20)-1)

                        impact_mean = mean(solv_matr(m,1:2)); %taking the mean of the upper and lower of the impact
                        t_impact_3 = n*20; %current year (every 20 years)
                        solve_matr_time_develop(m,(t_impact_3+1))= impact_mean/((1+discountrate_average)^t_impact_3);

                    end
                end

            end

            solve_matr_time_develop;
            solve_matr_time_develop_indi_sum = sum(solve_matr_time_develop(:,1:end));

            for k = 1:size(solve_matr_time_develop,1)
                solve_matr_time_develop_indi_sum_vert(k,1) = sum(solve_matr_time_develop(k,1:end-1));
            end


            solve_matr_time_develop_indi_sum_vert;

            %progressiv developing cashflow sum
            solve_matr_time_develop_sum(1,1)= solve_matr_time_develop_indi_sum(1,1);

            for t = 1:(length(solve_matr_time_develop_indi_sum)-1)
                solve_matr_time_develop_sum(1,t+1) = solve_matr_time_develop_sum(1,t) + solve_matr_time_develop_indi_sum(1,t+1);
            end


            %Plotting
            hold(app.plot_cashflowovertime,'on')
            plot(app.plot_cashflowovertime,1:length(solve_matr_time_develop_indi_sum),solve_matr_time_develop_indi_sum,'Marker','.','Color',['#82B366'])
            plot(app.plot_cashflowovertime,1:length(solve_matr_time_develop_sum),solve_matr_time_develop_sum,'Marker','.','Color',['#808080'])
            yline(app.plot_cashflowovertime,0,'LineStyle','--')

            plot(app.plot_cashflowovertime,[0,1],[0,solve_matr_time_develop_indi_sum(1,1)],'Color',['#82B366']) %Lazy line from origin

            %general

            xlabel(app.plot_cashflowovertime,'years');
            ylabel(app.plot_cashflowovertime,'€')
            legend(app.plot_cashflowovertime,{'Cashflow','Accumulated Cashflow'})

            hold(app.plot_cashflowovertime,'off')


            %Bar diagramm B/C influences ------------------------

            hold(app.plot_impactbars,'on')
            solv_matr_mean = mean(solv_matr_average(:,1:2),2);


            solv_matr_develop(1,:) = [0 0];

            for i = 1:length(solv_matr_mean)-1
                solv_matr_develop(i,2) = solv_matr_develop(i,1)+solv_matr_mean(i,1);
                if i < length(solv_matr_mean)-1
                    solv_matr_develop(i+1,1) = solv_matr_develop(i,2);
                end

            end


            %Plot Horizontal lines
            for i = 1:(size(solv_matr_develop,1)-1)
                plot(app.plot_impactbars,[i,i+1],[solv_matr_develop(i+1,1),solv_matr_develop(i+1,1)],'Color',['#696969'])
            end


            v = transpose(solv_matr_develop);


            %Iterative box generation
            for v_dis = 1:size(v,2)

                if v(1,v_dis) <= v(2,v_dis)
                    c = [0.51 0.7 0.4];
                else
                    c = [1 0.4 0.4];
                end

                patch_x = [v_dis-0.25;v_dis+0.25;v_dis+0.25;v_dis-0.25];
                patch_y = [v(2,v_dis);v(2,v_dis);v(1,v_dis);v(1,v_dis)];
                patch(app.plot_impactbars,patch_x,patch_y,c);
            end


            yline(app.plot_impactbars,0,'LineStyle','--')

            %general
            %set(gca, 'XTick', 1:v_dis,'XTickLabel',solv_matr_labels)

            ylabel(app.plot_impactbars,'€')

            xticks(app.plot_impactbars,1:v_dis)
            xticklabels(app.plot_impactbars,app.solv_matr_labels)
            xtickangle(app.plot_impactbars,45)

            hold(app.plot_impactbars,'on')


            %General Fig sum cost/benefit
            if app.calculate_var == 1
                chosen_plot = app.plot_costbenefit
            elseif app.calculate_var == 2
                chosen_plot = app.plot_costbenefit_GBC
            elseif app.calculate_var == 3
                chosen_plot = app.plot_costbenefit_Urb
            end

            sum_pos_imp = sum(solv_matr_mean(solv_matr_mean > 0))
            sum_neg_imp = sum(solv_matr_mean(solv_matr_mean < 0))
            sum_benefit = sum_pos_imp - abs(sum_neg_imp);

            hold(chosen_plot,'on')

            bar(chosen_plot,[1],[most_prob_ICC],'FaceColor',[1 0.4 0.4])
            bar(chosen_plot,[2],[sum_neg_imp],'FaceColor',[1 0.4 0.4])
            bar(chosen_plot,[3],[sum_pos_imp],'FaceColor',[0.51 0.7 0.4])
            bar(chosen_plot,[4],[sum_benefit],'FaceColor',[0.93,0.69,0.13])

            yline(chosen_plot,0,'LineStyle','--')

            xticks(chosen_plot,1:4)
            xticklabels(chosen_plot,{'Initial Investment','Costs','Pos. cashflow','net. benefit'})
            ylabel(chosen_plot,'NPV in €')

            hold(chosen_plot,'off')


        end


        function calc_start(app)
            app.CalculateButton_GR.BackgroundColor =[0.50,0.50,0.50];
        end


        function calc_stop(app)
            app.CalculateButton_GR.BackgroundColor =[0.96,0.96,0.96];
        end


        function initclear_fig(app)

            cla(app.plot_costbenefit)
            cla(app.plot_costbenefit_GBC)
            cla(app.plot_costbenefit_Urb)
            cla(app.plot_Impacts)
            cla(app.plot_impactbars)
            cla(app.plot_cashflowovertime)
            cla(app.plot_montecarlo)


        end

        function impactmatr(app)

        end

        function calc_bar(app)


            if app.calculate_var == 1
                app.CalculateButton = app.CalculateButton_GR
            elseif app.calculate_var == 2
                app.CalculateButton = app.CalculateButton_GBC
            elseif app.calculate_var == 3
                app.CalculateButton = app.CalculateButton_Urb
            end


            %calculating animation 
            %start proccessing animation
            % Change button name to "Processing"
            app.CalculateButton.Text = 'Calculating...';
            % Put text on top of icon
            app.CalculateButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalculateButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalculateButton.Icon = wbar;
            % Loop through something and update waitbar
            n = 10;
            for i = 1:n
                % Update image data (royalblue)
                if mod(i,10)==0 % update every 10 cycles; improves efficiency
                    currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                    RGB = app.CalculateButton.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.CalculateButton.Icon = RGB;
                    % Pause to slow down animation
                    pause(.3)
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            clear;
            fprintf('start')

            %Set Panels in correct visibility state

            app.panel_homescreen.Visible='on';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 1;


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: 
        % InformationaboutNatureBasedSolutionsButton
        function InformationaboutNatureBasedSolutionsButtonPushed(app, event)
            %go to nbs info panel
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='on';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 2;
        end

        % Button pushed function: ChangeInputsButton
        function ChangeInputsButtonPushed(app, event)
            %go to Green Roff calc
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='on';

        end

        % Button pushed function: button_back_NBSinfo
        function button_back_NBSinfoButtonPushed(app, event)
            %go to homescreen
            app.panel_homescreen.Visible='on';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 1;
        end

        % Button pushed function: button_back_extresults
        function button_back_extresultsButtonPushed(app, event)
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_calculator_inputs.Visible ='off';


            if app.current_panel == 3
                app.panel_greenroof_calculator.Visible='on';
                app.panel_bluegreencorridor_calculator.Visible='off';
                app.panel_urbanforest_calculator.Visible='off';

            elseif app.current_panel == 4
                app.panel_greenroof_calculator.Visible='off';
                app.panel_bluegreencorridor_calculator.Visible='off';
                app.panel_urbanforest_calculator.Visible='on';

            elseif app.current_panel == 5
                app.panel_greenroof_calculator.Visible='off';
                app.panel_bluegreencorridor_calculator.Visible='on';
                app.panel_urbanforest_calculator.Visible='off';
            else
                %Go back to homescreen
                app.panel_homescreen.Visible='on';
                app.panel_extresults.Visible='off';
                app.panel_nbsinfo.Visible='off';
                app.panel_bluegreencorridor_calculator.Visible='off';
                app.panel_greenroof_calculator.Visible='off';
                app.panel_urbanforest_calculator.Visible='off';
                app.panel_calculator_inputs.Visible ='off';

                app.current_panel = 1;
            end
        end

        % Button pushed function: GreenRooPageButton
        function GreenRooPageButtonPushed(app, event)
            %go to Green Roff calc
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='on';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 3;
        end

        % Button pushed function: UrbanForestButton
        function UrbanForestButtonPushed(app, event)
            %go to Urban forest calc
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='on';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 4;
        end

        % Button pushed function: button_GBC_extresults, 
        % button_greenroof_extresults, button_urb_extresults_2
        function button_extresultsPushed(app, event)

            %go to additional statistica results
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='on';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';


        end

        % Button pushed function: GreenBlueCorridorButton
        function GreenBlueCorridorButtonPushed(app, event)
            %go to Blue Green corridor calc
            app.panel_homescreen.Visible='off';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='on';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 5;
        end

        % Button pushed function: 
        % button_back2homescreen_bluegreencorridor, 
        % button_back2homescreen_inputs, 
        % button_back2homescreen_urbanforest, button_back_greenroof
        function button_back2homescreen(app, event)
            %Go back to homescreen
            app.panel_homescreen.Visible='on';
            app.panel_extresults.Visible='off';
            app.panel_nbsinfo.Visible='off';
            app.panel_bluegreencorridor_calculator.Visible='off';
            app.panel_greenroof_calculator.Visible='off';
            app.panel_urbanforest_calculator.Visible='off';
            app.panel_calculator_inputs.Visible ='off';

            app.current_panel = 1;
        end

        % Button pushed function: CalculateButton_GR
        function CalculateButtonGRPushed(app, event)

            initclear_fig(app) %clear all app figures

            app.calculate_var = 1;

            %Init Inputs
            app.timespan_chosen = app.TimeFrameyearsSpinner.Value; %init chosen time frame
            m_2_gr = app.GreenRoofSizem2Spinner.Value; % init chosen Green Roof Site
            averag_rain = app.AverageRaininAreaperMonthmmMonthSpinner.Value

            app.CalculateButton_GR.BackgroundColor =[0.80,0.80,0.80];

            %checking of other inputs for unequal to 0 & displaying
            %Error Message

            if app.GreenRoofSizem2Spinner.Value == 0 || app.AverageRaininAreaperMonthmmMonthSpinner.Value == 0

                app.LabelErrorInput.Visible = true;
                return;
            else
                app.LabelErrorInput.Visible = false;
            end


            %Initiating of Impact Matrix
            %Green Roof extensive
            if app.IntensiveButton.Value == 1

                if averag_rain < 120 %Intensive with Irrigation


                    %Impact Matrix Green Roof Intensive
                    imp_matr_gr_intensive = [
                        -128.39,-420.17,2,  0; %ICC
                        141.23, 504.21, 2,  1; %Prooperty Value
                        0,      49.18,  2,  1; %Tax Reduction
                        0,      22.56,  2,  1; %Acoustic Insulation
                        -0.0,   -0.13,  3,  1; %Landfill Cost
                        -4.59,  -10.49, 3,  1; %Air pollution release
                        77.81,	252.1,  2,  1; %Stormwater drain reduction
                        6.22,   20.23,  2,  1; %Reduction of Stormwatetr infrastructure
                        0,      15.87,  2,  1; %Habitat Creation
                        6.46,	33.61,  2,  1; %Aestethics
                        4.67,	10.89,  2,  1; %Provision of Recreational Spaces
                        -7,      -17.53,  2,  1; % Irrigation System Installation Cost
                        -0.54,	-10.5, 2,  2;  %Operation and Maintanance
                        0,	    0.30,   2,  2; %Stormwater retention
                        0.17,	0.17,   4,  2; %Energy Consumption Cooling
                        0.14,	0.53,   2,  2; %Energy Consupmtion Heating
                        0.00,	0.00,   2,  2; %Carbon Reduction
                        0.02,	0.02,   2,  2; %Air quality improvement
                        0,	    0.00,   2,  2; %Reduction of flood risk
                        0.01,	0.03,   2,  2; %mitigation of urban heat island effects
                        -12*0.35*(0.12-(averag_rain*10^-3)),-12*4.83*(0.12-(averag_rain*10^-3)), 2, 3; %Irrigation Water Cost
                        26.36,	67.90,  2,  3; %Longevity benefit
                        ];

                    imp_matr_gr_intensive(:,1:2) = imp_matr_gr_intensive(:,1:2).*m_2_gr

                    imp_matr_gr_intensive_label = {
                        'ICC';
                        'Prooperty Value';
                        'Tax Reduction';
                        'Acoustic Insulation';
                        'Landfill Cost';
                        'Air pollution release';
                        'Stormwater drain reduction';
                        'Reduction of Stormwatetr infrastructure';
                        'Habitat Creation';
                        'Aestethics';
                        'Provision of recreational spaces';
                        'Irrigation System Installation Cost';
                        'Operation and Maintanance';
                        'Stormwater retention';
                        'Energy Consumption Cooling';
                        'Energy Consupmtion Heating';
                        'Carbon Reduction';
                        'Air quality improvement';
                        'Reduction of flood risk';
                        'mitigation of urban heat island effects';
                        'Irrigation Water Cost';
                        'Longevity benefit';
                        };

                    app.solv_matr = imp_matr_gr_intensive;
                    app.solv_matr_labels = imp_matr_gr_intensive_label;


                elseif averag_rain >= 120 %Intensive without Irrigation

                    %Impact Matrix Green Roof Intensive
                    imp_matr_gr_intensive = [
                        -128.39,-420.17,2,  0; %ICC
                        141.23, 504.21, 2,  1; %Prooperty Value
                        0,      49.18,  2,  1; %Tax Reduction
                        0,      22.56,  2,  1; %Acoustic Insulation
                        -0.0,   -0.13,  3,  1; %Landfill Cost
                        -4.59,  -10.49, 3,  1; %Air pollution release
                        77.81,	252.1,  2,  1; %Stormwater drain reduction
                        6.22,   20.23,  2,  1; %Reduction of Stormwatetr infrastructure
                        0,      15.87,  2,  1; %Habitat Creation
                        6.46,	33.61,  2,  1; %Aestethics
                        4.67,	10.89,  2,  1; %Provision of Recreational Spaces
                        -0.54,	-10.5, 2,  2;  %Operation and Maintanance
                        0,	    0.30,   2,  2; %Stormwater retention
                        0.17,	0.17,   4,  2; %Energy Consumption Cooling
                        0.14,	0.53,   2,  2; %Energy Consupmtion Heating
                        0.00,	0.00,   2,  2; %Carbon Reduction
                        0.02,	0.02,   2,  2; %Air quality improvement
                        0,	    0.00,   2,  2; %Reduction of flood risk
                        0.01,	0.03,   2,  2; %mitigation of urban heat island effects
                        26.36,	67.90,  2,  3; %Longevity benefit
                        ];

                    imp_matr_gr_intensive(:,1:2) = imp_matr_gr_intensive(:,1:2).*m_2_gr

                    imp_matr_gr_intensive_label = {
                        'ICC';
                        'Prooperty Value';
                        'Tax Reduction';
                        'Acoustic Insulation';
                        'Landfill Cost';
                        'Air pollution release';
                        'Stormwater drain reduction';
                        'Reduction of Stormwatetr infrastructure';
                        'Habitat Creation';
                        'Aestethics';
                        'Provision of recreational spaces';
                        'Operation and Maintanance';
                        'Stormwater retention';
                        'Energy Consumption Cooling';
                        'Energy Consupmtion Heating';
                        'Carbon Reduction';
                        'Air quality improvement';
                        'Reduction of flood risk';
                        'mitigation of urban heat island effects';
                        'Longevity benefit';
                        };

                    app.solv_matr = imp_matr_gr_intensive;
                    app.solv_matr_labels = imp_matr_gr_intensive_label;

                end

                % Green Roof intensive

            elseif app.ExtensiveButton.Value == 1


                if averag_rain < 120 %Extensive with Irrigation

                    imp_matr_gr_extensive = [
                        -101.15,-128.39,2,  0; %ICC
                        103.18, 135.39, 2,  1; %Prooperty Value
                        0,      49.18,  2,  1; %Tax Reduction
                        0,      22.56,  2,  1; %Acoustic Insulation
                        -0.01,  -0.16,  2,  1; %Landfill Cost
                        -10.94, -17.27, 2,  1; %Air pollution release
                        30.35,	77.81,  2,  1; %Stormwater drain reduction
                        6.22,   20.23,  2,  1; %Reduction of Stormwatetr infrastructure
                        0,      7.94,   2,  1; %Habitat Creation
                        2.02,	6.46,   2,  1; %Aestethics
                        -7,      -17.53,  2,  1; % Irrigation System Installation Cost
                        -0.51,	-10.47, 2,  2; %Operation and Maintanance
                        0,	    0.30,   2,  2; %Stormwater retention
                        0.17,	0.17,   4,  2; %Energy Consumption Cooling
                        0.14,	0.53,   2,  2; %Energy Consupmtion Heating
                        0.00,	0.00,   2,  2; %Carbon Reduction
                        0.02,	0.02,   2,  2; %Air quality improvement
                        0,	    0.00,   2,  2; %Reduction of flood risk
                        0.01,	0.03,   2,  2; %mitigation of urban heat island effects
                        -12*0.35*(0.12-(averag_rain*10^-3)),-12*4.83*(0.12-(averag_rain*10^-3)), 2, 3; %Irrigation Water Cost
                        26.36,	67.90,  2,  3; %Longevity benefit
                        ];

                    imp_matr_gr_extensive(:,1:2) = imp_matr_gr_extensive(:,1:2).*m_2_gr


                    imp_matr_gr_extensive_label = {
                        'ICC';
                        'Prooperty Value';
                        'Tax Reduction';
                        'Acoustic Insulation';
                        'Landfill Cost';
                        'Air pollution release';
                        'Stormwater drain reduction';
                        'Reduction of Stormwatetr infrastructure';
                        'Habitat Creation';
                        'Aestethics';
                        'Irrigation System Installation Cost';
                        'Operation and Maintanance';
                        'Stormwater retention';
                        'Energy Consumption Cooling';
                        'Energy Consupmtion Heating';
                        'Carbon Reduction';
                        'Air quality improvement';
                        'Reduction of flood risk';
                        'mitigation of urban heat island effects';
                        'Irrigation Water Cost';
                        'Longevity benefit';
                        };

                    app.solv_matr = imp_matr_gr_extensive;
                    app.solv_matr_labels = imp_matr_gr_extensive_label;


                elseif averag_rain >= 120 %Extensive without Irrigation

                    imp_matr_gr_extensive = [
                        -101.15,-128.39,2,  0; %ICC
                        103.18, 135.39, 2,  1; %Prooperty Value
                        0,      49.18,  2,  1; %Tax Reduction
                        0,      22.56,  2,  1; %Acoustic Insulation
                        -0.01,  -0.16,  2,  1; %Landfill Cost
                        -10.94, -17.27, 2,  1; %Air pollution release
                        30.35,	77.81,  2,  1; %Stormwater drain reduction
                        6.22,   20.23,  2,  1; %Reduction of Stormwatetr infrastructure
                        0,      7.94,   2,  1; %Habitat Creation
                        2.02,	6.46,   2,  1; %Aestethics
                        -0.51,	-10.47, 2,  2; %Operation and Maintanance
                        0,	    0.30,   2,  2; %Stormwater retention
                        0.17,	0.17,   4,  2; %Energy Consumption Cooling
                        0.14,	0.53,   2,  2; %Energy Consupmtion Heating
                        0.00,	0.00,   2,  2; %Carbon Reduction
                        0.02,	0.02,   2,  2; %Air quality improvement
                        0,	    0.00,   2,  2; %Reduction of flood risk
                        0.01,	0.03,   2,  2; %mitigation of urban heat island effects
                        26.36,	67.90,  2,  3; %Longevity benefit
                        ];

                    imp_matr_gr_extensive(:,1:2) = imp_matr_gr_extensive(:,1:2).*m_2_gr

                    imp_matr_gr_extensive_label = {
                        'ICC';
                        'Prooperty Value';
                        'Tax Reduction';
                        'Acoustic Insulation';
                        'Landfill Cost';
                        'Air pollution release';
                        'Stormwater drain reduction';
                        'Reduction of Stormwatetr infrastructure';
                        'Habitat Creation';
                        'Aestethics';
                        'Operation and Maintanance';
                        'Stormwater retention';
                        'Energy Consumption Cooling';
                        'Energy Consupmtion Heating';
                        'Carbon Reduction';
                        'Air quality improvement';
                        'Reduction of flood risk';
                        'mitigation of urban heat island effects';
                        'Longevity benefit';
                        };

                    app.solv_matr = imp_matr_gr_extensive;
                    app.solv_matr_labels = imp_matr_gr_extensive_label;

                end
            else
                fprintf('Neither extensive nor Intensive selected')
                return;
            end


            %greenroof_size = app.GreenRoofSizem2Spinner.Value %taking over size into function


            gg_calculate(app) % call calculation fcnt ---------


            app.NetPresentValueEditField.Value = app.most_prob_NPV;

            app.RateofReturnEditField.Value = app.most_prob_RR;
            app.CostBenefitRatioEditField.Value = app.most_prob_CBR;


            %traffic light
            if app.most_prob_CBR <= 1

                app.Lamp_gr_red.Color = [0.96,0.96,0.96] %%
                app.Lamp_gr_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_gr_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR > 1 && app.most_prob_CBR < 1.5

                app.Lamp_gr_red.Color = [0.98,0.82,0.82]
                app.Lamp_gr_yellow.Color = [0.93,0.69,0.13] %%
                app.Lamp_gr_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR >= 1.5

                app.Lamp_gr_red.Color = [0.98,0.82,0.82]
                app.Lamp_gr_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_gr_green.Color = [0.00,1.00,0.00]%%

            end

            calc_stop(app)

            %set label of active Result for extended Results page
            app.Label_activeResults.Text = {'Green Blue Corridor'}


        end

        % Button pushed function: CalculateButton_GBC
        function CalculateButton_GBCPushed(app, event)
            initclear_fig(app) %clear all figures

            app.calculate_var = 2;

            %init Inputs
            tree_nmbr = app.SpinnerNmbrTreesGBC.Value;
            m2_grassarea = app.SpinnerGrassAreaGBC.Value;
            m2_retention = app.SpinnerRetAreaGBC.Value;
            m2_permpavement = app.SpinnerAreaPermPaveGBC.Value;
            average_rain = app.SpinnerAverageRainGBC.Value;
            sum_area = app.SpinnerAreaOverallGBC.Value;
            app.timespan_chosen = app.SpinnerTimeFrameGBC.Value;

            trees_persqm = tree_nmbr/sum_area;


            if tree_nmbr == 0 || m2_grassarea == 0 || m2_retention == 0 || m2_permpavement == 0 || average_rain == 0 || sum_area == 0 || app.timespan_chosen == 0

                app.LabelErrorInput_GBC.Visible = true;
                return;
            else
                app.LabelErrorInput_GBC.Visible = false;
            end
            %Impact Matrix ---------------------------------------------------
            %Row structures: (lower limit, upperlimit, distribution, time-frame)
            %->distribution (dist: 1 = normal, 2 = uniform, 3 = triangular, 4 = const)
            %   in case of constant: lowerlimit = upper, insert in lower limit !!!!!)
            % ->time-frame 1 = one time, 2 = yearly, 3 = every 20 years, 0 =
            % initial construction cost

            %Urban Forest & Green Blue Corridor Impact Matrixes


            %Tree Planting
            imp_matr_treeplanting = [
                -106.89, -554.64,1, 0; %ICC Tree
                -9.07,   -24.20,1,  2; % maintanance Tree
                3.59,   148.97, 2,  2; %
                0.29,   0.29,   2,  2;
                -0.6,   19.98,  2,  2;
                0.21*trees_persqm,      0.63*trees_persqm,      1,  2;
                5.69*trees_persqm,      5.69*trees_persqm,      2,  2;
                15.01,  139.9,  1,  2;
                1.78,   7.66,   2,  2;
                0,      0.89*trees_persqm,      1,  2;
                ];

            imp_matr_treeplanting(:,1:2) = imp_matr_treeplanting(:,1:2).*tree_nmbr;


            imp_matr_treeplanting_label = {
                'ICC Tree';
                'maintanance Tree';
                'Energy consump. red. Tree';
                'Carbon Reduction';
                'Air quality';
                'Reduction of urban heat';
                'Health improvement';
                'Land and property value';
                'Water management';
                'Noise'};


            %Green areas
            imp_matr_greenareas = [
                0,      0,          4,  0;
                0.01,   0.01,       2,  2;
                0,      3.54,       2,  2;
                0.0185, 0.0233,     2,  2;
                0.21,   0.63,       1,  2;
                5.69,   5.69,       4,  2;
                116.13, 232.26,     2,  1;
                0,      0.89,       1,  2;
                ];

            imp_matr_greenareas(:,1:2) = imp_matr_greenareas(:,1:2).*m2_grassarea;


            imp_matr_greenareas_label = {
                'ICC Green areas'
                'Carbon Reduction Green Area';
                'Biodiversity Green Area';
                'Air Quality Green Area';
                'Red. of Urb. Heat Green Area';
                'Health Improvement Green Area';
                'Land & Property Value';
                'Noise'
                };


            %drainage system
            imp_matr_drainsystem = [
                -13.03,   -58.65,       2,  0;
                -1.44,    -4.32,       2,  2;
                0.014,      0.014,      4,  2;
                1.42*0.69*average_rain*10^-3,   1.42*0.85*average_rain*10^-3,     2,  2;
                ];


            imp_matr_drainsystem(:,1:2) = imp_matr_drainsystem(:,1:2).*m2_retention;


            imp_matr_drainsystem_label = {
                'ICC drainage system ';
                'maintanance drainage sytsem';
                'Water management drainage system';
                };

            %dpermeable pavement
            imp_matr_permpave = [
                -92.91,   -278.82,       2,  0;
                -2.32,    -4.64,       2,  2;
                1.42*0.69,   1.42*0.85,     2,  2;
                ];


            imp_matr_permpave(:,1:2) = imp_matr_permpave(:,1:2).*m2_permpavement;


            imp_matr_permpave_label = {
                'ICC drainage system ';
                'maintanance drainage sytsem';
                'Water management drainage system';
                };


            %create calculation matrix out of different modules


            app.solv_matr = [
                imp_matr_treeplanting;
                imp_matr_greenareas;
                imp_matr_drainsystem;
                imp_matr_permpave;
                ];

            app.solv_matr_labels = [
                imp_matr_treeplanting_label;
                imp_matr_greenareas_label;
                imp_matr_drainsystem_label;
                imp_matr_permpave_label;
                ];

            gg_calculate(app) %call calculation fcnt


            %Give Outputs
            app.NetPresentValueEditField_GBC.Value = app.most_prob_NPV;
            app.RateofReturnEditField_GBC.Value = app.most_prob_RR;
            app.CostBenefitRatioEditField_GBC.Value = app.most_prob_CBR;


            %Traffic light
            if app.most_prob_CBR <= 1

                app.Lamp_GBC_red.Color = [0.96,0.96,0.96] %%
                app.Lamp_GBC_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_GBC_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR > 1 && app.most_prob_CBR < 1.5

                app.Lamp_GBC_red.Color = [0.98,0.82,0.82]
                app.Lamp_GBC_yellow.Color = [0.93,0.69,0.13] %%
                app.Lamp_GBC_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR >= 1.5

                app.Lamp_GBC_red.Color = [0.98,0.82,0.82]
                app.Lamp_GBC_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_GBC_green.Color = [0.00,1.00,0.00]%%

            end

            %set label of active Result for extended Results page
            app.Label_activeResults.Text = {'Green Blue Corridor'}


        end

        % Button pushed function: CalculateButton_Urb
        function CalculateButton_UrbPushed(app, event)
            initclear_fig(app) %clear all figures

            app.calculate_var = 3;

            %init Inputs
            tree_nmbr = app.SpinnerNmbrTreesUrb.Value;
            m2_grassarea = app.SpinnerGrassAreaUrb.Value;
            m2_retention = app.SpinnerRetAreaUrb.Value;
            average_rain = app.SpinnerAverageRainUrb.Value;
            sum_area = app.SpinnerAreaOverallUrb_2.Value;
            app.timespan_chosen = app.SpinnerTimeFrameUrb.Value;

            trees_persqm = tree_nmbr/sum_area;


            if tree_nmbr == 0 || m2_grassarea == 0 || m2_retention == 0 || average_rain == 0 || sum_area == 0 || app.timespan_chosen == 0

                app.LabelErrorInput_Urb.Visible = true;
                return;
            else
                app.LabelErrorInput_Urb.Visible = false;
            end
            %Impact Matrix ---------------------------------------------------
            %Row structures: (lower limit, upperlimit, distribution, time-frame)
            %->distribution (dist: 1 = normal, 2 = uniform, 3 = triangular, 4 = const)
            %   in case of constant: lowerlimit = upper, insert in lower limit !!!!!)
            % ->time-frame 1 = one time, 2 = yearly, 3 = every 20 years, 0 =
            % initial construction cost

            %Urban Forest & Green Blue Corridor Impact Matrixes


            %Tree Planting
            imp_matr_treeplanting = [
                -106.89, -554.64,1, 0; %ICC Tree
                -9.07,   -24.20,1,  2; % maintanance Tree
                3.59,   148.97, 2,  2; %
                0.29,   0.29,   2,  2;
                -0.6,   19.98,  2,  2;
                0.21*trees_persqm,      0.63*trees_persqm,      1,  2;
                5.69*trees_persqm,      5.69*trees_persqm,      2,  2;
                15.01,  139.9,  1,  2;
                1.78,   7.66,   2,  2;
                0,      0.89*trees_persqm,      1,  2;
                ];

            imp_matr_treeplanting(:,1:2) = imp_matr_treeplanting(:,1:2).*tree_nmbr;


            imp_matr_treeplanting_label = {
                'ICC Tree';
                'maintanance Tree';
                'Energy consump. red. Tree';
                'Carbon Reduction';
                'Air quality';
                'Reduction of urban heat';
                'Health improvement';
                'Land and property value';
                'Water management';
                'Noise'};


            %Green areas
            imp_matr_greenareas = [
                0,      0,          4,  0;
                0.01,   0.01,       2,  2;
                0,      3.54,       2,  2;
                0.0185, 0.0233,     2,  2;
                0.21,   0.63,       1,  2;
                5.69,   5.69,       4,  2;
                116.13, 232.26,     2,  1;
                0,      0.89,       1,  2;
                ];

            imp_matr_greenareas(:,1:2) = imp_matr_greenareas(:,1:2).*m2_grassarea;


            imp_matr_greenareas_label = {
                'ICC Green areas'
                'Carbon Reduction Green Area';
                'Biodiversity Green Area';
                'Air Quality Green Area';
                'Red. of Urb. Heat Green Area';
                'Health Improvement Green Area';
                'Land & Property Value';
                'Noise'
                };


            %drainage system
            imp_matr_drainsystem = [
                -13.03,   -58.65,       2,  0;
                -1.44,    -4.32,       2,  2;
                0.014,      0.014,      4,  2;
                1.42*0.69*average_rain*10^-3,   1.42*0.85*average_rain*10^-3,     2,  2;
                ];


            imp_matr_drainsystem(:,1:2) = imp_matr_drainsystem(:,1:2).*m2_retention;


            imp_matr_drainsystem_label = {
                'ICC drainage system ';
                'maintanance drainage sytsem';
                'Water management drainage system';
                };


            %create calculation matrix out of different modules


            app.solv_matr = [
                imp_matr_treeplanting;
                imp_matr_greenareas;
                imp_matr_drainsystem;
                ];

            app.solv_matr_labels = [
                imp_matr_treeplanting_label;
                imp_matr_greenareas_label;
                imp_matr_drainsystem_label;
                ];

            gg_calculate(app) %call calculation fcnt


            %Give Outputs
            app.NetPresentValueEditField_Urb.Value = app.most_prob_NPV;
            app.RateofReturnEditField_Urb.Value = app.most_prob_RR;
            app.CostBenefitRatioEditField_Urb.Value = app.most_prob_CBR;


            %Traffic light
            if app.most_prob_CBR <= 1

                app.Lamp_Urb_red.Color = [0.96,0.96,0.96] %%
                app.Lamp_Urb_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_Urb_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR > 1 && app.most_prob_CBR < 1.5

                app.Lamp_Urb_red.Color = [0.98,0.82,0.82]
                app.Lamp_Urb_yellow.Color = [0.93,0.69,0.13] %%
                app.Lamp_Urb_green.Color = [0.79,0.94,0.79]

            elseif app.most_prob_CBR >= 1.5

                app.Lamp_Urb_red.Color = [0.98,0.82,0.82]
                app.Lamp_Urb_yellow.Color = [0.98,0.93,0.81]
                app.Lamp_Urb_green.Color = [0.00,1.00,0.00]%%

            end

            %set label of active Result for extended Results page
            app.Label_activeResults.Text = {'Green Blue Corridor'}


        end

        % Callback function
        function button_saveresultsButtonPushed(app, event)
            getframe(app.panel_greenroof_calculator,[100,100,1720,980])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.panel_calculator_inputs = uipanel(app.UIFigure);
            app.panel_calculator_inputs.Title = 'Calculator Inputs';
            app.button_back2homescreen_inputs = uibutton(app.panel_calculator_inputs, 'push');
            app.button_back2homescreen_inputs.ButtonPushedFcn = createCallbackFcn(app, @button_back2homescreen, true);
            app.button_back2homescreen_inputs.Text = 'Back';
            app.TabGroup = uitabgroup(app.panel_calculator_inputs);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.panel_urbanforest_calculator = uipanel(app.UIFigure);
            app.panel_urbanforest_calculator.Title = 'Urban Forest Calculator';
            app.Image4_2 = uiimage(app.panel_urbanforest_calculator);
            app.Image4_2.ImageSource = 'background copy.jpg';
            app.button_back2homescreen_urbanforest = uibutton(app.panel_urbanforest_calculator, 'push');
            app.button_back2homescreen_urbanforest.ButtonPushedFcn = createCallbackFcn(app, @button_back2homescreen, true);
            app.button_back2homescreen_urbanforest.Text = 'Back';
            app.CalculateButton_Urb = uibutton(app.panel_urbanforest_calculator, 'push');
            app.CalculateButton_Urb.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_UrbPushed, true);
            app.CalculateButton_Urb.Text = 'Calculate';
            app.ResultsLabel_2 = uilabel(app.panel_urbanforest_calculator);
            app.ResultsLabel_2.Text = 'Results ';
            app.LabelErrorInput_Urb = uilabel(app.panel_urbanforest_calculator);
            app.LabelErrorInput_Urb.Visible = 'off';
            app.LabelErrorInput_Urb.Text = 'Missing Inputs, please check again';
            app.NPVLabel_Urb = uilabel(app.panel_urbanforest_calculator);
            app.NPVLabel_Urb.Text = 'Net Present Value [€] ';
            app.NetPresentValueEditField_Urb = uieditfield(app.panel_urbanforest_calculator, 'numeric');
            app.NetPresentValueEditField_Urb.ValueDisplayFormat = '%g';
            app.NetPresentValueEditField_Urb.Editable = 'off';
            app.button_urb_extresults_2 = uibutton(app.panel_urbanforest_calculator, 'push');
            app.button_urb_extresults_2.ButtonPushedFcn = createCallbackFcn(app, @button_extresultsPushed, true);
            app.button_urb_extresults_2.Text = 'Probabilistic Results';
            app.InputsLabel_2 = uilabel(app.panel_urbanforest_calculator);
            app.InputsLabel_2.Text = 'Inputs';
            app.Panel_2 = uipanel(app.panel_urbanforest_calculator);
            app.plot_costbenefit_Urb = uiaxes(app.Panel_2);
            zlabel(app.plot_costbenefit_Urb, 'Z')
            app.RateofReturnEditFieldLabel_Urb = uilabel(app.panel_urbanforest_calculator);
            app.RateofReturnEditFieldLabel_Urb.Text = 'Rate of Return';
            app.RateofReturnEditField_Urb = uieditfield(app.panel_urbanforest_calculator, 'numeric');
            app.RateofReturnEditField_Urb.Editable = 'off';
            app.CostBenefitRatioEditFieldLabel_Urb = uilabel(app.panel_urbanforest_calculator);
            app.CostBenefitRatioEditFieldLabel_Urb.Text = 'Cost Benefit Ratio';
            app.CostBenefitRatioEditField_Urb = uieditfield(app.panel_urbanforest_calculator, 'numeric');
            app.CostBenefitRatioEditField_Urb.Editable = 'off';
            app.Button_2 = uibutton(app.panel_urbanforest_calculator, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Lamp_Urb_red = uilamp(app.panel_urbanforest_calculator);
            app.Lamp_Urb_yellow = uilamp(app.panel_urbanforest_calculator);
            app.Lamp_Urb_green = uilamp(app.panel_urbanforest_calculator);
            app.SpinnerLabelNmbrTreesUrb = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelNmbrTreesUrb.Text = 'Number of trees';
            app.SpinnerNmbrTreesUrb = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerNmbrTreesUrb.Limits = [0 Inf];
            app.SpinnerLabelTetAreaUrb = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelTetAreaUrb.Text = 'Retention Area [m^2]';
            app.SpinnerRetAreaUrb = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerRetAreaUrb.Limits = [0 Inf];
            app.SpinnerLabelTimeFrameUrb = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelTimeFrameUrb.Text = 'Time Frame [years]';
            app.SpinnerTimeFrameUrb = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerTimeFrameUrb.Limits = [0 Inf];
            app.SpinnerLabelGrassAreaUrb = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelGrassAreaUrb.Text = 'Grass Area [m^2]';
            app.SpinnerGrassAreaUrb = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerGrassAreaUrb.Limits = [0 Inf];
            app.SpinnerLabelAverageRainUrb = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelAverageRainUrb.Text = 'Average Rain [mm]';
            app.SpinnerAverageRainUrb = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerAverageRainUrb.Limits = [0 Inf];
            app.SpinnerLabelAreaOverallUrb_2 = uilabel(app.panel_urbanforest_calculator);
            app.SpinnerLabelAreaOverallUrb_2.Text = 'Are overall [m^2]';
            app.SpinnerAreaOverallUrb_2 = uispinner(app.panel_urbanforest_calculator);
            app.SpinnerAreaOverallUrb_2.Limits = [0 Inf];
            app.panel_greenroof_calculator = uipanel(app.UIFigure);
            app.panel_greenroof_calculator.Title = 'Green Roof Calculator';
            app.Image4_4 = uiimage(app.panel_greenroof_calculator);
            app.Image4_4.ImageSource = 'background copy.jpg';
            app.TypeofGreenRoofButtonGroup = uibuttongroup(app.panel_greenroof_calculator);
            app.TypeofGreenRoofButtonGroup.Title = 'Type of Green Roof';
            app.ExtensiveButton = uiradiobutton(app.TypeofGreenRoofButtonGroup);
            app.ExtensiveButton.Text = 'Extensive';
            app.ExtensiveButton.Value = true;
            app.IntensiveButton = uiradiobutton(app.TypeofGreenRoofButtonGroup);
            app.IntensiveButton.Text = 'Intensive';
            app.GreenRoofSizem2SpinnerLabel = uilabel(app.panel_greenroof_calculator);
            app.GreenRoofSizem2SpinnerLabel.Text = 'Green Roof Size [m^2]';
            app.GreenRoofSizem2Spinner = uispinner(app.panel_greenroof_calculator);
            app.GreenRoofSizem2Spinner.Limits = [0 Inf];
            app.AverageRaininAreaperMonthmmMonthLabel = uilabel(app.panel_greenroof_calculator);
            app.AverageRaininAreaperMonthmmMonthLabel.Text = {'Average Rain in Area '; 'per Month [mm/Month]'};
            app.AverageRaininAreaperMonthmmMonthSpinner = uispinner(app.panel_greenroof_calculator);
            app.AverageRaininAreaperMonthmmMonthSpinner.Limits = [0 Inf];
            app.TimeFrameyearsSpinnerLabel = uilabel(app.panel_greenroof_calculator);
            app.TimeFrameyearsSpinnerLabel.Text = 'Time Frame [years]';
            app.TimeFrameyearsSpinner = uispinner(app.panel_greenroof_calculator);
            app.TimeFrameyearsSpinner.Limits = [0 Inf];
            app.CalculateButton_GR = uibutton(app.panel_greenroof_calculator, 'push');
            app.CalculateButton_GR.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonGRPushed, true);
            app.CalculateButton_GR.Text = 'Calculate';
            app.ResultsLabel = uilabel(app.panel_greenroof_calculator);
            app.ResultsLabel.Text = 'Results ';
            app.LabelErrorInput = uilabel(app.panel_greenroof_calculator);
            app.LabelErrorInput.Visible = 'off';
            app.LabelErrorInput.Text = 'Error in Inputs, please check again';
            app.NPVLabel = uilabel(app.panel_greenroof_calculator);
            app.NPVLabel.Text = 'Net Present Value [€] ';
            app.NetPresentValueEditField = uieditfield(app.panel_greenroof_calculator, 'numeric');
            app.NetPresentValueEditField.ValueDisplayFormat = '%g';
            app.NetPresentValueEditField.Editable = 'off';
            app.button_greenroof_extresults = uibutton(app.panel_greenroof_calculator, 'push');
            app.button_greenroof_extresults.ButtonPushedFcn = createCallbackFcn(app, @button_extresultsPushed, true);
            app.button_greenroof_extresults.Text = 'Probabilistic Results';
            app.DisplayedarethemostprobableResultsLabel = uilabel(app.panel_greenroof_calculator);
            app.DisplayedarethemostprobableResultsLabel.Text = 'Displayed are the most probable Results';
            app.button_back_greenroof = uibutton(app.panel_greenroof_calculator, 'push');
            app.button_back_greenroof.ButtonPushedFcn = createCallbackFcn(app, @button_back2homescreen, true);
            app.button_back_greenroof.Text = 'Back';
            app.InputsLabel = uilabel(app.panel_greenroof_calculator);
            app.InputsLabel.Text = 'Inputs';
            app.Panel = uipanel(app.panel_greenroof_calculator);
            app.plot_costbenefit = uiaxes(app.Panel);
            zlabel(app.plot_costbenefit, 'Z')
            app.RateofReturnEditFieldLabel = uilabel(app.panel_greenroof_calculator);
            app.RateofReturnEditFieldLabel.Text = 'Rate of Return';
            app.RateofReturnEditField = uieditfield(app.panel_greenroof_calculator, 'numeric');
            app.RateofReturnEditField.Editable = 'off';
            app.CostBenefitRatioEditFieldLabel = uilabel(app.panel_greenroof_calculator);
            app.CostBenefitRatioEditFieldLabel.Text = 'Cost Benefit Ratio';
            app.CostBenefitRatioEditField = uieditfield(app.panel_greenroof_calculator, 'numeric');
            app.CostBenefitRatioEditField.Editable = 'off';
            app.Button = uibutton(app.panel_greenroof_calculator, 'push');
            app.Button.Text = '';
            app.Lamp_gr_red = uilamp(app.panel_greenroof_calculator);
            app.Lamp_gr_yellow = uilamp(app.panel_greenroof_calculator);
            app.Lamp_gr_green = uilamp(app.panel_greenroof_calculator);
            app.KeepTimeFrameatzeroforprobabilisticLabel = uilabel(app.panel_greenroof_calculator);
            app.KeepTimeFrameatzeroforprobabilisticLabel.Text = 'Keep Time Frame at zero for probabilistic';
            app.lifetimeanalysis4055yearsLabel = uilabel(app.panel_greenroof_calculator);
            app.lifetimeanalysis4055yearsLabel.Text = 'lifetime analysis [40 -55 years]';
            app.panel_extresults = uipanel(app.UIFigure);
            app.panel_extresults.Title = 'Extended Results:';
            app.plot_montecarlo = uiaxes(app.panel_extresults);
            zlabel(app.plot_montecarlo, 'Z')
            app.plot_cashflowovertime = uiaxes(app.panel_extresults);
            zlabel(app.plot_cashflowovertime, 'Z')
            app.plot_impactbars = uiaxes(app.panel_extresults);
            zlabel(app.plot_impactbars, 'Z')
            app.plot_Impacts = uiaxes(app.panel_extresults);
            zlabel(app.plot_Impacts, 'Z')
            app.button_back_extresults = uibutton(app.panel_extresults, 'push');
            app.button_back_extresults.ButtonPushedFcn = createCallbackFcn(app, @button_back_extresultsButtonPushed, true);
            app.button_back_extresults.Text = 'Back';
            app.Label_activeResults = uilabel(app.panel_extresults);
            app.Label_activeResults.Text = '-/-';
            app.panel_bluegreencorridor_calculator = uipanel(app.UIFigure);
            app.panel_bluegreencorridor_calculator.Title = 'Green Blue Corridor Calculator';
            app.Image4_3 = uiimage(app.panel_bluegreencorridor_calculator);
            app.Image4_3.ImageSource = 'background copy.jpg';
            app.button_back2homescreen_bluegreencorridor = uibutton(app.panel_bluegreencorridor_calculator, 'push');
            app.button_back2homescreen_bluegreencorridor.ButtonPushedFcn = createCallbackFcn(app, @button_back2homescreen, true);
            app.button_back2homescreen_bluegreencorridor.Text = 'Back';
            app.CalculateButton_GBC = uibutton(app.panel_bluegreencorridor_calculator, 'push');
            app.CalculateButton_GBC.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_GBCPushed, true);
            app.CalculateButton_GBC.Text = 'Calculate';
            app.ResultsLabel_3 = uilabel(app.panel_bluegreencorridor_calculator);
            app.ResultsLabel_3.Text = 'Results ';
            app.LabelErrorInput_GBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.LabelErrorInput_GBC.Visible = 'off';
            app.LabelErrorInput_GBC.Text = 'Missing Inputs, please check again';
            app.NPVLabel_GBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.NPVLabel_GBC.Text = 'Net Present Value [€] ';
            app.NetPresentValueEditField_GBC = uieditfield(app.panel_bluegreencorridor_calculator, 'numeric');
            app.NetPresentValueEditField_GBC.RoundFractionalValues = 'on';
            app.NetPresentValueEditField_GBC.ValueDisplayFormat = '%g';
            app.NetPresentValueEditField_GBC.Editable = 'off';
            app.button_GBC_extresults = uibutton(app.panel_bluegreencorridor_calculator, 'push');
            app.button_GBC_extresults.ButtonPushedFcn = createCallbackFcn(app, @button_extresultsPushed, true);
            app.button_GBC_extresults.Text = 'Probabilistic Results';
            app.InputsLabel_3 = uilabel(app.panel_bluegreencorridor_calculator);
            app.InputsLabel_3.Text = 'Inputs';
            app.Panel_3 = uipanel(app.panel_bluegreencorridor_calculator);
            app.plot_costbenefit_GBC = uiaxes(app.Panel_3);
            zlabel(app.plot_costbenefit_GBC, 'Z')
            app.RateofReturnEditFieldLabel_GBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.RateofReturnEditFieldLabel_GBC.Text = 'Rate of Return';
            app.RateofReturnEditField_GBC = uieditfield(app.panel_bluegreencorridor_calculator, 'numeric');
            app.RateofReturnEditField_GBC.Editable = 'off';
            app.CostBenefitRatioEditFieldLabel_GBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.CostBenefitRatioEditFieldLabel_GBC.Text = 'Cost Benefit Ratio';
            app.CostBenefitRatioEditField_GBC = uieditfield(app.panel_bluegreencorridor_calculator, 'numeric');
            app.CostBenefitRatioEditField_GBC.Editable = 'off';
            app.Button_3 = uibutton(app.panel_bluegreencorridor_calculator, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Lamp_GBC_red = uilamp(app.panel_bluegreencorridor_calculator);
            app.Lamp_GBC_yellow = uilamp(app.panel_bluegreencorridor_calculator);
            app.Lamp_GBC_green = uilamp(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelNmbrTreesGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelNmbrTreesGBC.Text = 'Number of trees';
            app.SpinnerNmbrTreesGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerNmbrTreesGBC.Limits = [0 Inf];
            app.SpinnerLabelTetAreaGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelTetAreaGBC.Text = 'Retention Area [m^2]';
            app.SpinnerRetAreaGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerRetAreaGBC.Limits = [0 Inf];
            app.SpinnerLabelTimeFrameGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelTimeFrameGBC.Text = 'Time Frame [years]';
            app.SpinnerTimeFrameGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerTimeFrameGBC.Limits = [0 Inf];
            app.SpinnerLabelGrassAreaGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelGrassAreaGBC.Text = 'Grass Area [m^2]';
            app.SpinnerGrassAreaGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerGrassAreaGBC.Limits = [0 Inf];
            app.SpinnerLabelAreaPermPaveGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelAreaPermPaveGBC.Text = 'Area Permeable Pavement [m^2]';
            app.SpinnerAreaPermPaveGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerAreaPermPaveGBC.Limits = [0 Inf];
            app.SpinnerLabelAverageRainGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelAverageRainGBC.Text = 'Average Rain [mm]';
            app.SpinnerAverageRainGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerAverageRainGBC.Limits = [0 Inf];
            app.SpinnerLabelAreaOverallGBC = uilabel(app.panel_bluegreencorridor_calculator);
            app.SpinnerLabelAreaOverallGBC.Text = 'Area in total [m^2]';
            app.SpinnerAreaOverallGBC = uispinner(app.panel_bluegreencorridor_calculator);
            app.SpinnerAreaOverallGBC.Limits = [0 Inf];
            app.panel_nbsinfo = uipanel(app.UIFigure);
            app.panel_nbsinfo.Title = 'Nature Based Solutions Information';
            app.button_back_NBSinfo = uibutton(app.panel_nbsinfo, 'push');
            app.button_back_NBSinfo.ButtonPushedFcn = createCallbackFcn(app, @button_back_NBSinfoButtonPushed, true);
            app.button_back_NBSinfo.Text = 'Back';
            app.Image = uiimage(app.panel_nbsinfo);
            app.Image.ImageSource = '20210708081417-green-roof-construction.jpg';
            app.Image2 = uiimage(app.panel_nbsinfo);
            app.Image2.ImageSource = 'urban-forest.jpg';
            app.Image3 = uiimage(app.panel_nbsinfo);
            app.Image3.ImageSource = 'blue-green-corridors-elysian-dry.jpg';
            app.TextArea = uitextarea(app.panel_nbsinfo);
            app.TextArea.Value = {'A green roof or living roof is a roof of a building that is partially or completely covered with vegetation and a growing medium, planted over a waterproofing membrane. It may also include additional layers such as a root barrier and drainage and irrigation systems'};
            app.GreenRoofLabel = uilabel(app.panel_nbsinfo);
            app.GreenRoofLabel.Text = 'Green Roof';
            app.UrbanForestLabel = uilabel(app.panel_nbsinfo);
            app.UrbanForestLabel.Text = 'Urban Forest';
            app.GreenBlueCorridorLabel = uilabel(app.panel_nbsinfo);
            app.GreenBlueCorridorLabel.Text = 'Green Blue Corridor';
            app.TextArea_2 = uitextarea(app.panel_nbsinfo);
            app.TextArea_2.Value = {'In Valencia the urban forest is part of the extension of the Benicalap Parc (chapter ‎2) and is created to increase biodiversity and manage stormwater. Therefore the design is based on three core elements: topography, vegetation and water.'};
            app.TextArea_3 = uitextarea(app.panel_nbsinfo);
            app.TextArea_3.Value = {'The GBC is created to be a pedestrian route, that connects different green spaces together. But there are also some other purposes like improving thermal comfort, and capturing CO2. Additionally, it creates a safe space for pedestrians and increases the biodiversity.'};
            app.panel_homescreen = uipanel(app.UIFigure);
            app.Image_backgrnd = uiimage(app.panel_homescreen);
            app.Image_backgrnd.ImageSource = 'background copy.jpg';
            app.GreenRooPageButton = uibutton(app.panel_homescreen, 'push');
            app.GreenRooPageButton.ButtonPushedFcn = createCallbackFcn(app, @GreenRooPageButtonPushed, true);
            app.GreenRooPageButton.Text = 'Green Roof';
            app.CloseButton = uibutton(app.panel_homescreen, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.GreenBlueCorridorButton = uibutton(app.panel_homescreen, 'push');
            app.GreenBlueCorridorButton.ButtonPushedFcn = createCallbackFcn(app, @GreenBlueCorridorButtonPushed, true);
            app.GreenBlueCorridorButton.Text = 'Green Blue Corridor';
            app.UrbanForestButton = uibutton(app.panel_homescreen, 'push');
            app.UrbanForestButton.ButtonPushedFcn = createCallbackFcn(app, @UrbanForestButtonPushed, true);
            app.UrbanForestButton.Text = 'Urban Forest';
            app.InformationaboutNatureBasedSolutionsButton = uibutton(app.panel_homescreen, 'push');
            app.InformationaboutNatureBasedSolutionsButton.ButtonPushedFcn = createCallbackFcn(app, @InformationaboutNatureBasedSolutionsButtonPushed, true);
            app.InformationaboutNatureBasedSolutionsButton.Text = 'Information about Nature Based Solutions';
            app.GrowGreenImpactCalculatorLabel = uilabel(app.panel_homescreen);
            app.GrowGreenImpactCalculatorLabel.Text = 'Grow Green Impact Calculator';
            app.ChangeInputsButton = uibutton(app.panel_homescreen, 'push');
            app.ChangeInputsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeInputsButtonPushed, true);
            app.ChangeInputsButton.Visible = 'off';
            app.ChangeInputsButton.Text = 'Change Inputs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V3_GGCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end